classdef CambiamentoImmaginiSatellitari < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChangeUIFigure           matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        StartDetectionButton     matlab.ui.control.Button
        ChangestoVisualizePanel  matlab.ui.container.Panel
        CambiaDropDown           matlab.ui.control.DropDown
        YearsofReferencePanel    matlab.ui.container.Panel
        AnnoFinaleDropDown       matlab.ui.control.DropDown
        FinalYearDropDownLabel   matlab.ui.control.Label
        AnnoInizialeDropDown     matlab.ui.control.DropDown
        StartYearDropDownLabel   matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        TabGroup                 matlab.ui.container.TabGroup
        OriginalImagesTab        matlab.ui.container.Tab
        GridLayout2              matlab.ui.container.GridLayout
        Immagine2Label           matlab.ui.control.Label
        Immagine1Label           matlab.ui.control.Label
        Image_2                  matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        DetectionTab             matlab.ui.container.Tab
        ReverseColorsLabel       matlab.ui.control.Label
        LampColor_2              matlab.ui.control.Lamp
        LampColor                matlab.ui.control.Lamp
        SwitchColors             matlab.ui.control.Switch
        hSlider                  matlab.ui.control.Slider
        hSliderLabel             matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shipped with the product.
    
    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        Data
        SelectedGenders
        SelectedColors
        BinWidth
        Histogram = gobjects(0)
        displayedIndices
    end
    
    properties (Access = public)
        pathIm1
        pathIm2
        file1
        file2
        isFileSystem = false
    end
    
    methods (Access = private)
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ChangeUIFigure.Name = 'Satellite Image Changes';
            axis(app.UIAxes,'off');
            app.LampColor.Color = "white";
            app.LampColor_2.Color = "black";
            run inizializzaComponenti;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ChangeUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {400, 400};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {282, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function SliderValueChanging(app, event)
            % Update the histogram as the slider value for bindwidth changes.
            app.BinWidth = event.Value;
            for ii=1:length(app.Histogram)
                app.Histogram(ii).NumBins = numhistbins(app,app.Histogram(ii).Data);
            end
        end

        % Value changed function: CambiaDropDown
        function updateSelectedGenders(app, event)
            app.isFileSystem = false;
            run inizializzaComponenti;
        end

        % Value changing function: hSlider
        function hSliderValueChanging2(app, event)
            value = event.Value;

            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value if you plan on using it within this function
            value = event.Source.MajorTicks(minIdx);
            app.hSlider.Value = value;
            run pca_kmeans_gui;
        end

        % Value changed function: AnnoInizialeDropDown
        function AnnoInizialeDropDownValueChanged(app, event)
            value = app.AnnoInizialeDropDown.Value;
            app.isFileSystem = false;
            run cambiaImmagineIniziale;
        end

        % Value changed function: AnnoFinaleDropDown
        function AnnoFinaleDropDownValueChanged(app, event)
            value = app.AnnoFinaleDropDown.Value;
            app.isFileSystem = false;
            run cambiaImmagineFinale;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.isFileSystem = true;
            [app.file1, app.pathIm1] = cambiaImmagineFileSystem(app.Image);
            if ~isequal(app.file1,0)
                app.Immagine1Label.Text = app.file1;
            end
        end

        % Image clicked function: Image_2
        function Image_2Clicked(app, event)
            app.isFileSystem = true;
            [app.file2, app.pathIm2] = cambiaImmagineFileSystem(app.Image_2);
            if ~isequal(app.file2, 0)
                app.Immagine2Label.Text = app.file2;
            end
        end

        % Button pushed function: StartDetectionButton
        function StartDetectionButtonPushed(app, event)
            run pca_kmeans_gui;
        end

        % Value changed function: SwitchColors
        function SwitchColorsValueChanged2(app, event)
            value = app.SwitchColors.Value;
            colorLamp1 = app.LampColor.Color;
            if colorLamp1 == [1 1 1]
                app.LampColor_2.Color = "white";
                app.LampColor.Color = "black";
            else
                app.LampColor_2.Color = "black";
                app.LampColor.Color = "white";
            end
            I = imread("change_map.png");
            reversedImg = imcomplement(I);
            imshow(reversedImg, 'Parent' , app.UIAxes);
            imwrite(reversedImg,'change_map.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChangeUIFigure = uifigure('Visible', 'off');
            app.ChangeUIFigure.Name = 'Patients Display';
            app.ChangeUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.ChangeUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.YearsofReferencePanel = uipanel(app.LeftPanel);
            app.YearsofReferencePanel.Title = 'Years of Reference';
            app.StartYearDropDownLabel = uilabel(app.YearsofReferencePanel);
            app.StartYearDropDownLabel.Text = 'Start Year';
            app.AnnoInizialeDropDown = uidropdown(app.YearsofReferencePanel);
            app.AnnoInizialeDropDown.Items = {};
            app.AnnoInizialeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnnoInizialeDropDownValueChanged, true);
            app.AnnoInizialeDropDown.Value = {};
            app.FinalYearDropDownLabel = uilabel(app.YearsofReferencePanel);
            app.FinalYearDropDownLabel.Text = 'Final Year';
            app.AnnoFinaleDropDown = uidropdown(app.YearsofReferencePanel);
            app.AnnoFinaleDropDown.Items = {};
            app.AnnoFinaleDropDown.ValueChangedFcn = createCallbackFcn(app, @AnnoFinaleDropDownValueChanged, true);
            app.AnnoFinaleDropDown.Value = {};
            app.ChangestoVisualizePanel = uipanel(app.LeftPanel);
            app.ChangestoVisualizePanel.Title = 'Changes to Visualize';
            app.CambiaDropDown = uidropdown(app.ChangestoVisualizePanel);
            app.CambiaDropDown.Items = {'lakes', 'glaciers', 'forest', 'coast', 'fires'};
            app.CambiaDropDown.ValueChangedFcn = createCallbackFcn(app, @updateSelectedGenders, true);
            app.CambiaDropDown.Value = 'lakes';
            app.StartDetectionButton = uibutton(app.LeftPanel, 'push');
            app.StartDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartDetectionButtonPushed, true);
            app.StartDetectionButton.Text = 'Start Detection';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.OriginalImagesTab = uitab(app.TabGroup);
            app.OriginalImagesTab.Title = 'Original Images';
            app.GridLayout2 = uigridlayout(app.OriginalImagesTab);
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image_2 = uiimage(app.GridLayout2);
            app.Image_2.ImageClickedFcn = createCallbackFcn(app, @Image_2Clicked, true);
            app.Immagine1Label = uilabel(app.GridLayout2);
            app.Immagine1Label.Text = 'Immagine 1';
            app.Immagine2Label = uilabel(app.GridLayout2);
            app.Immagine2Label.Text = 'Immagine 2';
            app.DetectionTab = uitab(app.TabGroup);
            app.DetectionTab.Title = 'Detection';
            app.UIAxes = uiaxes(app.DetectionTab);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.hSliderLabel = uilabel(app.DetectionTab);
            app.hSliderLabel.Text = 'h';
            app.hSlider = uislider(app.DetectionTab);
            app.hSlider.Limits = [2 10];
            app.hSlider.MajorTicks = [2 4 6 8 10];
            app.hSlider.ValueChangingFcn = createCallbackFcn(app, @hSliderValueChanging2, true);
            app.hSlider.MinorTicks = [2 4 6 8 10];
            app.hSlider.Value = 2;
            app.SwitchColors = uiswitch(app.DetectionTab, 'slider');
            app.SwitchColors.Items = {'', ''};
            app.SwitchColors.ValueChangedFcn = createCallbackFcn(app, @SwitchColorsValueChanged2, true);
            app.SwitchColors.Value = '';
            app.LampColor = uilamp(app.DetectionTab);
            app.LampColor_2 = uilamp(app.DetectionTab);
            app.ReverseColorsLabel = uilabel(app.DetectionTab);
            app.ReverseColorsLabel.Text = 'Reverse Colors';
            % Show the figure after all components are created
            app.ChangeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CambiamentoImmaginiSatellitari

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ChangeUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.ChangeUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChangeUIFigure)
        end
    end
end